classdef NeuralPDEInferenceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GeometryfileLabel   matlab.ui.control.Label
        ModeltypeLabel      matlab.ui.control.Label
        ModelfileLabel      matlab.ui.control.Label
        Switch              matlab.ui.control.Switch
        PredictButton       matlab.ui.control.Button
        LoadGeometryButton  matlab.ui.control.Button
        LoadAIModelButton   matlab.ui.control.Button
        GeometryAxes        matlab.ui.control.UIAxes
        PredictionAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        net       % Trained network (for transolver)
        p         % Train parameters (for MeshGraphNet)
        cfg       % Trained network settings
        model     % femodel 
        nodesForPlot 
        elemsForPlot
        PredPotential % The predicted electric potential
        ElectricField % Electric field derived from predicted potential
        ElectricFieldMagnitude % Magnitude of electric field derived from predicted potential
        hasGPU % 1 if machine has GPU, 0 else
        EFieldComputed % 1 if the Efield has already been computed, 0 else
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check whether a GPU exists, and if so, use it
            app.hasGPU = canUseGPU;
            app.PredictButton.Enable = 'off';
            app.Switch.Enable = 'off';      % enable only after first prediction
            app.EFieldComputed = false;
        end

        % Button pushed function: LoadAIModelButton
        function LoadAIModelButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Select Trained Model');
            if isequal(file,0)
                return; % user canceled
            end
            warning('off'); % otherwise get warnings about gpuArray when loading MGN
            S = load(fullfile(path,file));
            warning('on');
            app.cfg = S.cfg; 
            if strcmp(app.cfg.ModelName,"Transolver")
                app.net = S.net;
                if isfield(app.cfg, 'TrainingInfo') && ~isstruct(app.cfg.TrainingInfo)
                    close(app.cfg.TrainingInfo);
                end
            elseif strcmp(app.cfg.ModelName,"MeshGraphNet")
                if ~app.hasGPU
                    S.p = utils.convertGpuToCpu(S.p);
                end
                app.p = S.p;
            end 
            app.ModelfileLabel.Text = sprintf('Model file: %s', fullfile(file));
            app.ModeltypeLabel.Text = sprintf('Model type: %s', app.cfg.ModelName);

            % Reset any existing prediction when model changes
            app.PredPotential = [];
            app.EFieldComputed = false; 
            app.ElectricField = [];
            app.ElectricFieldMagnitude = [];
            % Reset/disable switch to its default
            app.Switch.Value = 'Electric Potential';
            app.Switch.Enable = 'off';

            % Clear plot so stale prediction is not visible
            if isvalid(app.PredictionAxes)
                cla(app.PredictionAxes,'reset');
            end
            % Disable predict until geometry is loaded 
            app.PredictButton.Enable = 'off';
            % Show message
            uialert(app.UIFigure, ...
                'Model loaded. Please (re)load geometry before predicting.', ...
                'Model Loaded',Icon='info');
        end

        % Button pushed function: LoadGeometryButton
        function LoadGeometryButtonPushed(app, event)
            [file,path] = uigetfile('*.stl','Select Geometry File');
            if isequal(file,0)
                return; % user canceled
            end
            % Require model first
            if isempty(app.cfg) || ~isfield(app.cfg,'ModelName')
                uialert(app.UIFigure, 'Load an AI model before loading geometry.', ...
                    'Missing Model',Icon='warning');
                return;
            end
            app.GeometryfileLabel.Text = sprintf('Geometry file: %s', fullfile(file));
            % build geometry from STL
            gmBushing = fegeometry(fullfile(path,file));
            if strcmp(app.cfg.ModelName,"Transolver")
                % Create femodel for entire domain (air + bushing)
                gmAir = multicuboid(0.4,0.4,1);
                gmAir = translate(gmAir,[0 0 -0.2]);
                gmAir = fegeometry(gmAir);
                gmModel = addCell(gmAir,gmBushing);
                % create femodel for electrostatic analysis
                app.model = femodel(AnalysisType="electrostatic",Geometry=gmModel);
                % Define the material properties needed for model inputs
                app.model.VacuumPermittivity = 8.8541878128E-12;            
                app.model.MaterialProperties(1) = materialProperties(RelativePermittivity=1); % Air
                app.model.MaterialProperties(2) = materialProperties(RelativePermittivity=5); % Bushing
                app.model = generateMesh(app.model,Hmax=0.025);
                app.nodesForPlot = findNodes(app.model.Mesh,"Region",Cell=2);
                app.elemsForPlot = findElements(app.model.Mesh,"Region",Cell=2);
            elseif strcmp(app.cfg.ModelName,"MeshGraphNet")
                % Create femodel for the bushing only 
                app.model = femodel(AnalysisType="electrostatic",Geometry=gmBushing);
                app.model.VacuumPermittivity = 8.8541878128E-12;            
                app.model.MaterialProperties = materialProperties(RelativePermittivity=5); % Bushing
                app.model = generateMesh(app.model,Hmax=0.025);
                app.nodesForPlot = 1:size(app.model.Mesh.Nodes,2);
                app.elemsForPlot = 1:size(app.model.Mesh.Elements,2);
            end
            % Enable predict
            app.PredictButton.Enable = 'on';
            % Clear old prediction state
            app.PredPotential = [];
            app.EFieldComputed = false;
            app.ElectricField = [];
            app.ElectricFieldMagnitude = [];
            % Reset/disable switch until a new prediction exists
            app.Switch.Value = 'Electric Potential';
            app.Switch.Enable = 'off';
            % Plot geometry
            if isprop(app,'GeometryAxes')
                cla(app.GeometryAxes,'reset'); % Clear previous plot
                pdegplot(gmBushing, Parent=app.GeometryAxes);
                title(app.GeometryAxes, 'Geometry');
            end
            % Clear prediction plot
            if isprop(app,'PredictionAxes')
                cla(app.PredictionAxes,'reset'); % Clear previous plot
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if isempty(app.cfg) || ~isfield(app.cfg,'ModelName')
                uialert(app.UIFigure,'Load a model first.','Missing Model',...
                    Icon='warning');
                return;
            end
            if isempty(app.model)
                uialert(app.UIFigure,'Load geometry first.','Missing Geometry',...
                    Icon='warning');
                return
            end
            switch app.cfg.ModelName
                % Transolver uses full domain (air + bushing)
                case 'Transolver'    
                    % Compute and store the elements and nodes corresponding to the transformer bushing
                    coord = app.model.Mesh.Nodes;
                    epsilon = zeros(size(app.model.Mesh.Nodes,2),1);
                    idxAir = findNodes(app.model.Mesh,"Region",Cell=1);
                    epsilon(idxAir) = app.model.MaterialProperties(1).RelativePermittivity;
                    epsilon(app.nodesForPlot) = app.model.MaterialProperties(2).RelativePermittivity;
                    % Prepare inputs
                    U = [coord; epsilon'];
                    % Normalize inputs
                    normalU = utils.applyScale(U,app.cfg.InputOffset,app.cfg.InputScale);
                    normalU = dlarray(normalU);
                    % Get prediction
                    if app.hasGPU
                        app.PredPotential = predict(app.net,gpuArray(normalU),ones(1, size(U,2)),InputDataFormats=["CS","CS"]);
                        app.PredPotential = extractdata(app.PredPotential);
                        app.PredPotential = gather(app.PredPotential);
                    else
                        app.PredPotential = predict(app.net,normalU,ones(1, size(U,2)),InputDataFormats=["CS","CS"]);
                        app.PredPotential = extractdata(app.PredPotential);
                    end
                    app.PredPotential = double(app.PredPotential);
                % MeshGraphNet uses only the bushing domain
                case 'MeshGraphNet'
                    keep = [];
                    [Xs,Xes,~,~,Bs] = meshgraphnet.preprocessData(app.model,keep);
                    % Normalize inputs
                    normalXs = utils.applyScale(Xs,app.cfg.InputOffset,app.cfg.InputScale);
                    normalXes = utils.applyScale(Xes,app.cfg.EdgeOffset,app.cfg.EdgeScale);
                    Bs = blkdiag(Bs);
                    Bs = Bs ./ sum(Bs,2);
                    % Get prediction
                    app.PredPotential = meshgraphnet.meshgraphnet(app.p,normalXs,normalXes,Bs,app.cfg);
                    app.PredPotential = extractdata(app.PredPotential);
                    app.PredPotential = app.PredPotential';
                    if app.hasGPU
                        app.PredPotential = gather(app.PredPotential);
                    end
                otherwise
                    error('No other models have been implemented yet.')
            end
            
            % Rescale prediction
            app.PredPotential = utils.applyInverseScale(app.PredPotential,app.cfg.OutputOffset,app.cfg.OutputScale);
             % Plot on full mesh but show only bushing
            cla(app.PredictionAxes,'reset');
            pdeplot3D(app.model.Mesh.Nodes, ...
              app.model.Mesh.Elements(:,app.elemsForPlot), ...
              ColorMapData=app.PredPotential,Parent=app.PredictionAxes);
            clim(app.PredictionAxes,[min(app.PredPotential(app.nodesForPlot)) max(app.PredPotential(app.nodesForPlot))]);
            title(app.PredictionAxes,'AI Predicted Electric Potential')

            app.EFieldComputed = false;
            app.ElectricField = [];
            app.ElectricFieldMagnitude = [];

            app.Switch.Enable = 'on'; % Enable switch after potential has been computed
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if isempty(app.PredPotential) || isempty(app.model)
                % Optional: warn once, or just return silently
                uialert(app.UIFigure,'No prediction yet. Click Predict first.', ...
                    'No Prediction', Icon='warning');
                return;
            end
            value = app.Switch.Value;
            switch value
                case 'Electric Potential'
                    cla(app.PredictionAxes,'reset');
                    pdeplot3D(app.model.Mesh.Nodes, ...
                      app.model.Mesh.Elements(:,app.elemsForPlot), ...
                      ColorMapData=app.PredPotential,Parent=app.PredictionAxes);
                    clim(app.PredictionAxes,[min(app.PredPotential(app.nodesForPlot)) max(app.PredPotential(app.nodesForPlot))]);
                    title(app.PredictionAxes, 'AI Predicted Electric Potential')
                case 'Electric Field Magnitude'
                    if ~app.EFieldComputed
                        % Compute derived quantities 
                        out = utils.electricFieldFromTets(app.model.Mesh.Nodes,app.model.Mesh.Elements,app.PredPotential);
                        app.ElectricField = out.E_node;
                        app.ElectricFieldMagnitude = out.Emag_node;
                        app.EFieldComputed = true;
                    end
                    cla(app.PredictionAxes,'reset');
                    pdeplot3D(app.model.Mesh.Nodes,app.model.Mesh.Elements(:,app.elemsForPlot),...
                            ColorMapData=app.ElectricFieldMagnitude,Parent=app.PredictionAxes);
                        clim(app.PredictionAxes,[min(app.ElectricFieldMagnitude(app.nodesForPlot)) max(app.ElectricFieldMagnitude(app.nodesForPlot))]);
                        title(app.PredictionAxes,'Electric Field Magnitude')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PredictionAxes = uiaxes(app.GridLayout);
            xlabel(app.PredictionAxes, 'X')
            ylabel(app.PredictionAxes, 'Y')
            zlabel(app.PredictionAxes, 'Z')
            app.GeometryAxes = uiaxes(app.GridLayout);
            xlabel(app.GeometryAxes, 'X')
            ylabel(app.GeometryAxes, 'Y')
            zlabel(app.GeometryAxes, 'Z')
            app.LoadAIModelButton = uibutton(app.GridLayout, 'push');
            app.LoadAIModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAIModelButtonPushed, true);
            app.LoadAIModelButton.Text = 'Load AI Model';
            app.LoadGeometryButton = uibutton(app.GridLayout, 'push');
            app.LoadGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGeometryButtonPushed, true);
            app.LoadGeometryButton.Text = 'Load Geometry';
            app.PredictButton = uibutton(app.GridLayout, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict!';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'Electric Potential', 'Electric Field Magnitude'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Electric Potential';
            app.ModelfileLabel = uilabel(app.GridLayout);
            app.ModelfileLabel.Text = 'Model file: ';
            app.ModeltypeLabel = uilabel(app.GridLayout);
            app.ModeltypeLabel.Text = 'Model type: ';
            app.GeometryfileLabel = uilabel(app.GridLayout);
            app.GeometryfileLabel.Text = 'Geometry file: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuralPDEInferenceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end